classdef ChangeDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HelpButton                   matlab.ui.control.Button
        DescriptionArea              matlab.ui.control.TextArea
        ParameterPanel               matlab.ui.container.Panel
        timlapselabelslider          matlab.ui.control.Label
        thresholdslider2             matlab.ui.control.Slider
        ThresholdLabel               matlab.ui.control.Label
        ThresholdSlider              matlab.ui.control.Slider
        BildnavigationPanel          matlab.ui.container.Panel
        PlayButton                   matlab.ui.control.Button
        PrevButton                   matlab.ui.control.Button
        NextButton                   matlab.ui.control.Button
        TimelapseSlider              matlab.ui.control.Slider
        StatusLabel                  matlab.ui.control.Label
        SpinnerImage                 matlab.ui.control.Image
        VisualisierungstartenButton  matlab.ui.control.Button
        ThemeToggleButton            matlab.ui.control.Button
        SegmentartLabel              matlab.ui.control.Label
        ClassCheckBox6               matlab.ui.control.CheckBox
        ClassCheckBox5               matlab.ui.control.CheckBox
        ClassCheckBox4               matlab.ui.control.CheckBox
        ClassCheckBox3               matlab.ui.control.CheckBox
        ClassCheckBox2               matlab.ui.control.CheckBox
        ClassCheckBox1               matlab.ui.control.CheckBox
        AnalyzeButton                matlab.ui.control.Button
        LoadFolderButton             matlab.ui.control.Button
        HauptauswahlPanel            matlab.ui.container.Panel
        SchnellerModusButton         matlab.ui.control.StateButton
        RestartButton                matlab.ui.control.Button
        TimelapseModeDropDown        matlab.ui.control.DropDown
        AbspielmodusTimelapseLabel   matlab.ui.control.Label
        ChangeSizeDropDownlabel      matlab.ui.control.Label
        ChangeSizeDropDown           matlab.ui.control.DropDown
        OrdnerStrukturLabel          matlab.ui.control.Label
        FolderTree                   matlab.ui.container.Tree
        Node                         matlab.ui.container.TreeNode
        Node2                        matlab.ui.container.TreeNode
        Node3                        matlab.ui.container.TreeNode
        Node4                        matlab.ui.container.TreeNode
        ChangeTypeDropDown           matlab.ui.control.DropDown
        ArtderVernderungLabel        matlab.ui.control.Label
        VisualizationDropDown        matlab.ui.control.DropDown
        ArtderVisualisierungLabel    matlab.ui.control.Label
        KIBildInfosPanel             matlab.ui.container.Panel
        ErweiterteauswahlPanel       matlab.ui.container.Panel
        ResultAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        LoadedPath
        ColorImages
        SegmentedMaps
        NumImages
        RegisteredImages
        ValidImageIndices
        TformList
        RefIndex
        TransformedSegmentedMasks
        ImageFiles
        SatObj  % Das SatelliteImageRegistration-Objekt
        ReferenceIndex         % refId
        Segmentations          % segmentedMaps
        TransformedSegmentations  % segmentedOverlapMasks
        CurrentTimelapseIndex = 2;
        TimelapseTimer = [];
        IsPlaying = false;
        ChangeSizeImages  % Bilder aus Groß/Klein-Analyse
        CurrentSegmentationIndex = 1;

    end

    
    properties (Access = private)
        isDarkMode = false; 
        TooltipLabel matlab.ui.control.Label % Tooltip-Anzeige
        HoverControls  
        FolderPath    


    end
    
    methods (Access = private)

        % function nextFrame(app)
        %     modus = app.TimelapseModeDropDown.Value;
        % 
        %     switch modus
        %         case 'Originalbilder'
        %             if isempty(app.RegisteredImages)
        %                 return;
        %             end
        %             maxIdx = numel(app.RegisteredImages);
        %             app.CurrentTimelapseIndex = mod(app.CurrentTimelapseIndex, maxIdx) + 1;
        %             showSingleTimelapseFrame(app);
        % 
        %         case 'Segmentierung'
        %             if isempty(app.TransformedSegmentations)
        %                 return;
        %             end
        %             maxIdx = numel(app.TransformedSegmentations);
        %             app.CurrentSegmentationIndex = mod(app.CurrentSegmentationIndex, maxIdx) + 1;
        %             showSegmentationImage(app);
        % 
        %         case 'Differenzansicht'
        %             if isempty(app.ChangeSizeImages)
        %                 return;
        %             end
        %             maxIdx = numel(app.ChangeSizeImages);
        %             app.CurrentTimelapseIndex = mod(app.CurrentTimelapseIndex, maxIdx) + 1;
        %             showChangeSizeImage(app);
        % 
        %         otherwise
        %             % kein gültiger Modus
        %             return;
        %     end
        % end

        function runNextFrame(app)
            modus = app.TimelapseModeDropDown.Value;
        
            % Groß/Klein – Differenz
            if strcmp(app.ChangeTypeDropDown.Value, 'Groß/Klein') && ...
               strcmp(app.VisualizationDropDown.Value, 'Differenz-Highlights')
                if app.CurrentTimelapseIndex < numel(app.ChangeSizeImages)
                    app.CurrentTimelapseIndex = app.CurrentTimelapseIndex + 1;
                    showChangeSizeImage(app);
                else
                    stop(app.TimelapseTimer);
                    app.IsPlaying = false;
                end
                return;
            end
        
            % Segmentierung
            if strcmp(modus, 'Segmentierung')
                if app.CurrentSegmentationIndex < numel(app.TransformedSegmentations)
                    app.CurrentSegmentationIndex = app.CurrentSegmentationIndex + 1;
                    showSegmentationImage(app);
                else
                    stop(app.TimelapseTimer);
                    app.IsPlaying = false;
                end
                return;
            end
        
            % Originalbilder oder Differenz
            maxIndex = numel(app.RegisteredImages);
            if strcmp(modus, 'Originalbilder')
                if app.CurrentTimelapseIndex < maxIndex
                    app.CurrentTimelapseIndex = app.CurrentTimelapseIndex + 1;
                else
                    app.CurrentTimelapseIndex = 1;
                end
            else  % Differenz
                if app.CurrentTimelapseIndex < maxIndex
                    app.CurrentTimelapseIndex = app.CurrentTimelapseIndex + 1;
                else
                    app.CurrentTimelapseIndex = 2;
                end
            end
        
            showSingleTimelapseFrame(app);
        end

        function showSegmentationImage(app)
            idx = app.CurrentSegmentationIndex;
            masks = app.TransformedSegmentations;
        
            if isempty(masks) || idx < 1 || idx > numel(app.ImageFiles)
                return;
            end
        
            % Plot mit Legende
            Plot_Class_Heatmap(masks(idx), app.ImageFiles(idx), ...
                               app.ResultAxes, 0:5);  % Klassen 0-5 zeigen
        
            % Titel mit Dateinamen (Jahr)
            [~, name, ~] = fileparts(app.ImageFiles{idx});
            year = name(end-3:end);
            title(app.ResultAxes, sprintf('%s', year), 'FontWeight', 'bold');
        end


        function addSpeedLegendToApp(app)
            % Entferne vorherige colorbar (falls vorhanden)
            delete(findall(app.UIFigure, 'Type', 'ColorBar'));
        
            % Benutzerdefinierte Colormap (Blau → Rot)
            n = 256;
            cmap = [linspace(0,1,n).' , zeros(n,1) , linspace(1,0,n).'];  % RGB: Blau → Rot
            colormap(app.ResultAxes, cmap);  % auf aktuelle Axes anwenden
        
            % Colorbar rechts außen anzeigen
            cb = colorbar(app.ResultAxes, 'Location', 'eastoutside');
        
            % Achsentitel & Labels
            cb.Ticks = [0 1];  % Nur Anfang und Ende
            cb.TickLabels = {'langsam', 'schnell'};
            cb.FontWeight = 'bold';
            cb.Label.String = 'Veränderungsgeschwindigkeit';
            cb.Label.FontWeight = 'bold';
        end

        function expandAllTreeNodes(app)
            rootNodes = app.FilesTree.Children;  % ← Tree-Name anpassen!
            for i = 1:numel(rootNodes)
                app.expandNodeRecursively(rootNodes(i));
            end
        end
    
        function expandNodeRecursively(app, node)
            node.Expanded = true;
            for i = 1:numel(node.Children)
                app.expandNodeRecursively(node.Children(i));
            end
        end
        
        function colorTreeByValidity(app)
            if isempty(app.SatObj)
                return;
            end
        
            files = app.ImageFiles;                % {'12_1990.jpg', ...}
            valid = app.SatObj.validIndices;       % [1 0 1 1 ...]
        
            rootNode = app.FolderTree.Children;
            if isempty(rootNode)
                return;
            end
        
            childNodes = rootNode.Children;
        
            for i = 1:numel(childNodes)
                nodeText = childNodes(i).Text;
        
                % Emoji entfernen
                if startsWith(nodeText, "🟢 ") || startsWith(nodeText, "🔴 ")
                    nodeText = extractAfter(nodeText, 3);
                end
        
                % Vergleiche nur den Dateinamen
                matchIdx = find(strcmp(nodeText, files), 1);
        
                if ~isempty(matchIdx) && matchIdx <= numel(valid)
                    if valid(matchIdx)
                        childNodes(i).Text = "🟢 " + nodeText;
                    else
                        childNodes(i).Text = "🔴 " + nodeText;
                    end
                else
                    % Optional: nichts ändern oder neutral darstellen
                    childNodes(i).Text = "⚪ " + nodeText;
                end
            end
        end


        function showChangeSizeImage(app)
            idx = app.CurrentTimelapseIndex;
            bilder = app.ChangeSizeImages;
        
            % Frühzeitiger Abbruch bei ungültigem Index oder leerem Cell-Array
            if isempty(bilder) || idx < 1 || idx > numel(bilder)
                return;
            end
        
            % Bild anzeigen
            imshow(bilder{idx}, 'Parent', app.ResultAxes);
        
            % Sicherstellen, dass der Endindex nicht überläuft
            nextIdx = min(idx + 1, numel(bilder));
        
            % Werte aus der GUI holen
            modus     = app.ChangeSizeDropDown.Value;
            threshold = app.ThresholdSlider.Value;
        
            % Titel formatieren und setzen
            fileA = app.ImageFiles{idx};
            fileB = app.ImageFiles{nextIdx};
            
            % Nur Namensteil (ohne Dateiendung)
            nameA = extractBefore(fileA, '.');
            nameB = extractBefore(fileB, '.');
            
            % Die letzten 4 Zeichen vor dem Punkt
            shortNameA = nameA(end-3:end);
            shortNameB = nameB(end-3:end);
            
            title(app.ResultAxes, ...
                sprintf('Veränderung: %s → %s (Modus=%s, Thr=%.2f)', ...
                        shortNameA, shortNameB, modus, threshold), ...
                'FontWeight', 'bold');
        end


        % function timelapseSlider_Update(app)
        %     bilder = app.RegisteredImages;
        %     if isempty(bilder) || numel(bilder) < 2
        %         return;
        %     end
        % 
        %     N = numel(bilder);
        %     v = app.TimelapseSlider.Value;
        %     i = floor(v);
        %     alpha = v - i;
        % 
        %     if i >= N
        %         img = bilder{N};
        %         frameText = sprintf('Frame %d / %d', N, N);
        %     else
        %         A = im2double(bilder{i});
        %         B = im2double(bilder{i+1});
        %         img = (1 - alpha)*A + alpha*B;
        %         frameText = sprintf('Frame %.2f / %d', v, N);
        %     end
        % 
        %     imshow(img, 'Parent', app.ResultAxes);
        %     title(app.ResultAxes, frameText, 'FontSize',12);
        % end
        function timelapseSlider_Update(app)
            bilder = app.RegisteredImages;
            yearsRaw = app.ImageFiles;  % Cell-Array oder string-Array mit Dateinamen
            
            if isempty(bilder)
                return;
            end
        
            N = numel(bilder);
            v = app.TimelapseSlider.Value;
        
            % Sanfter Übergang: ganzzahliger Index und Bruchteil
            i = floor(v);
            alpha = v - i;
        
            % Bild interpolieren
            if i >= N
                img = im2double(bilder{N});
            elseif i < 1
                img = im2double(bilder{1});
            else
                A = im2double(bilder{i});
                B = im2double(bilder{i+1});
                img = (1 - alpha)*A + alpha*B;
            end
        
            imshow(img, 'Parent', app.ResultAxes);
        
            % Jahr extrahieren: letzten 4 Ziffern vor '.'
            extractYear = @(fname) fname(max(end - 7,1):end-4); 
            % z. B. 'nananan2004.jpg' -> '2004'
        
            if i < 1
                yearPrev = extractYear(yearsRaw{1});
            else
                yearPrev = extractYear(yearsRaw{min(i, N)});
            end
        
            if i+1 > N
                yearCurr = extractYear(yearsRaw{N});
            else
                yearCurr = extractYear(yearsRaw{i+1});
            end
        
            title(app.ResultAxes, ...
                sprintf('%s → %s', yearPrev, yearCurr), ...
                'FontWeight', 'bold');
        end


        function stopTimelapseIfRunning(app)
            if ~isempty(app.TimelapseTimer) && isvalid(app.TimelapseTimer)
                stop(app.TimelapseTimer);
                delete(app.TimelapseTimer);
                app.TimelapseTimer = [];
            end
            app.IsPlaying = false;
        end

        function autoAdvanceTimelapse(app)
            modus = app.TimelapseModeDropDown.Value;
            maxIndex = numel(app.RegisteredImages);
        
            if strcmp(modus, 'Originalbilder')
                if app.CurrentTimelapseIndex < maxIndex
                    app.CurrentTimelapseIndex = app.CurrentTimelapseIndex + 1;
                else
                    app.CurrentTimelapseIndex = 1;  % Wieder von vorne
                end
            else
                if app.CurrentTimelapseIndex < maxIndex
                    app.CurrentTimelapseIndex = app.CurrentTimelapseIndex + 1;
                else
                    app.CurrentTimelapseIndex = 2;  % Zurück auf Differenzstart
                end
            end
        
            showSingleTimelapseFrame(app);
        end


        function advanceTimelapse(app)
            if app.CurrentTimelapseIndex >= numel(app.RegisteredImages)
                stop(app.TimelapseTimer);
                return;
            end
            app.CurrentTimelapseIndex = app.CurrentTimelapseIndex + 1;
            showSingleTimelapseFrame(app);
        end


        function showSingleTimelapseFrame(app)
            idx = app.CurrentTimelapseIndex;
            bilder = app.RegisteredImages;
            modus = app.TimelapseModeDropDown.Value;
            schwelle = app.ThresholdSlider.Value;
        
            if isempty(bilder) || idx < 1 || idx > numel(bilder)
                return;
            end
        
            if strcmp(modus, 'Originalbilder')
                % 🟢 Bild 1 direkt anzeigen (kein Vergleich nötig)
                imshow(bilder{idx}, 'Parent', app.ResultAxes);
                fileName = app.ImageFiles{idx};
                baseName = extractBefore(fileName, '.');
                shortName = baseName(end-3:end);  % z. B. "2023"
                
                % Titel setzen mit Name statt Index
                title(app.ResultAxes, sprintf('%s', shortName), ...
                      'FontWeight', 'bold');
            else
                if idx < 2
                    return; % 🛑 Differenzansicht benötigt mind. zwei Bilder
                end
        
                img1 = im2double(bilder{idx-1});
                img2 = im2double(bilder{idx});
        
                diff = imabsdiff(img1, img2);
                mask = rgb2gray(diff) > schwelle;
                overlay = cat(3, mask * 1, zeros(size(mask)), zeros(size(mask)));
                result = (1 - 0.6) * img2 + 0.6 * overlay;
        
                imshow(result, 'Parent', app.ResultAxes);
                % Dateinamen extrahieren
                name1 = extractBefore(app.ImageFiles{idx-1}, '.');
                name2 = extractBefore(app.ImageFiles{idx}, '.');
                
                % Letzte 4 Zeichen (z. B. Jahr) extrahieren
                if strlength(name1) >= 4
                    year1 = name1(end-3:end);
                else
                    year1 = name1;
                end
                
                if strlength(name2) >= 4
                    year2 = name2(end-3:end);
                else
                    year2 = name2;
                end
                
                % Titel setzen
                title(app.ResultAxes, sprintf('%s → %s', ...
                      year1, year2), ...
                      'FontWeight', 'bold');
            end
        end


        function updateHeatmapVisualization(app)
            schwelle = app.ThresholdSlider.Value;
            bilder = app.RegisteredImages;
    
            if isempty(bilder) || numel(bilder) < 2
                uialert(app.UIFigure, ...
                    'Bitte lade mindestens zwei registrierte Bilder über "Analyse starten".', ...
                    'Fehlende Daten');
                return;
            end
    
            % 🔁 Berechnung
            numImages = numel(bilder);
            [h, w, ~] = size(bilder{1});
            veraenderungsZaehler = zeros(h, w);
    
            for i = 2:numImages
                img1 = im2double(bilder{i-1});
                img2 = im2double(bilder{i});
                diffGray = rgb2gray(abs(img1 - img2));
                veraenderungsMaske = diffGray > schwelle;
                veraenderungsZaehler = veraenderungsZaehler + veraenderungsMaske;
            end
    
            heatmapNorm = veraenderungsZaehler / (numImages - 1);
    
            % 📊 Anzeige in ResultAxes
            imagesc(app.ResultAxes, heatmapNorm);
            colormap(app.ResultAxes, hot);
            axis(app.ResultAxes, 'image');
            app.ResultAxes.XTick = [];
            app.ResultAxes.YTick = [];
            title(app.ResultAxes, 'Heatmap der Veränderungshäufigkeit pro Pixel');
    
            % 🔢 Farbskala
            ch = colorbar(app.ResultAxes);
            ch.Label.String = sprintf('Veränderungshäufigkeit\n(0 = keine, %d = bei jedem Vergleich)', numImages - 1);
            ch.Ticks = linspace(0, 1, numImages);
            ch.TickLabels = arrayfun(@(x) sprintf('%dx', x), 0:numImages - 1, 'UniformOutput', false);
        end

        
        function callMouseMoveHandlers(app)
            %showTooltip(app);
            
        end

        function folderNodeSelected(app, event)
            node = event.SelectedNodes;
        
            if isempty(node) || isempty(node.NodeData)
                app.UIAxesPreview.Visible = 'off';
                return;
            end
        
            path = node.NodeData;
        
            if isfile(path) && app.isImageFile(path)
                try
                    % Bild laden
                    img = imread(path);
                
                    % Zielgröße
                    targetWidth = 200;
                    aspectRatio = 1064 / 1570;  % ~0.677
                    targetHeight = round(targetWidth * aspectRatio);
                
                    % Optional: auf 1570x1064 skalieren
                    if size(img,2) ~= 1570 || size(img,1) ~= 1064
                        img = imresize(img, [1064, 1570]);
                    end
                
                    % Temporär speichern (uiimage benötigt Datei)
                    tempPath = [tempname, '.png'];
                    imwrite(img, tempPath);
                
                    % Alte Position holen und Größe anpassen


                end

            end
        end


        function buildFolderTree(app, rootPath)
            % Tree leeren
            delete(app.FolderTree.Children);
            
            % Wurzelknoten
            rootNode = uitreenode(app.FolderTree, 'Text', rootPath, 'NodeData', rootPath);
            addSubfolders(app, rootNode, rootPath);
            %app.expandAllTreeNodes(); 
        end

        function tf = isImageFile(~,filename)
            [~,~,ext] = fileparts(filename);
            tf = ismember(lower(ext), {'.jpg','.jpeg','.png','.tif'});
        end
        function addSubfolders(app, parentNode, currentPath)
            folderContent = dir(currentPath);
            for i = 1:length(folderContent)
                item = folderContent(i);
                if item.isdir && ~startsWith(item.name, '.')
                    fullPath = fullfile(currentPath, item.name);
                    newNode = uitreenode(parentNode, 'Text', item.name, 'NodeData', fullPath);
                    addSubfolders(app, newNode, fullPath); % rekursiv weitergehen
                elseif app.isImageFile(item.name)
                    fullPath = fullfile(currentPath, item.name);
                    imageNode = uitreenode(parentNode, 'Text', item.name, 'NodeData', fullPath);
                end
            end
        end


        function showTooltip(app)
            cp = app.UIFigure.CurrentPoint;
            found = false;
    
            for i = 1:numel(app.HoverControls)
                ctrl = app.HoverControls{i};
                pos = ctrl.Position;
    
                if cp(1) >= pos(1) && cp(1) <= pos(1)+pos(3) && ...
                   cp(2) >= pos(2) && cp(2) <= pos(2)+pos(4)
                    % Tooltip anzeigen
                    app.TooltipLabel.Text = ctrl.UserData.Tooltip;
                    app.TooltipLabel.Position(1:2) = [cp(1)+10, cp(2)+10];
                    app.TooltipLabel.Visible = true;
                    found = true;
                    break;
                end
            end
    
            if ~found
                app.TooltipLabel.Visible = false;
            end
        end
        
        
        function applyDarkMode(app)
            bg = [0.1 0.1 0.1];
            fg = [1 1 1];
        
            % Hauptfenster
            app.UIFigure.Color = bg;
        
            % Panels
            panels = [ ...
                app.ParameterPanel, app.BildnavigationPanel, ...
                app.HauptauswahlPanel, app.KIBildInfosPanel, app.ErweiterteauswahlPanel ...
            ];
            for i = 1:numel(panels)
                panels(i).BackgroundColor = bg;
            end
        
            % % Sliders
            % sliders = [app.ThresholdSlider, app.thresholdslider2, app.TimelapseSlider];
            % for i = 1:numel(sliders)
            %     sliders(i).BackgroundColor = bg;
            %     sliders(i).FontColor = fg;
            % end
        
            % Labels (alle)
            labels = findall(app.UIFigure, 'Type', 'uilabel');
            for i = 1:numel(labels)
                labels(i).FontColor = fg;
                labels(i).BackgroundColor = 'none';
            end
        
            % TextArea
            app.DescriptionArea.BackgroundColor = bg;
            app.DescriptionArea.FontColor = fg;
        
            % % Checkboxes
            % for i = 1:6
            %     cb = app.(['ClassCheckBox' num2str(i)]);
            %     cb.FontColor = fg;
            %     cb.BackgroundColor = bg;
            % end
        
            % Dropdowns
            dropdowns = [ ...
                app.TimelapseModeDropDown, app.ChangeTypeDropDown, ...
                app.ChangeSizeDropDown, app.VisualizationDropDown ...
            ];
            for i = 1:numel(dropdowns)
                dropdowns(i).BackgroundColor = bg;
                dropdowns(i).FontColor = fg;
            end
        
            % Buttons
            buttons = [ ...
                app.PlayButton, app.PrevButton, app.NextButton, ...
                app.AnalyzeButton, app.LoadFolderButton, ...
                app.ThemeToggleButton, app.VisualisierungstartenButton, ...
                app.RestartButton, app.HelpButton ...
            ];
            for i = 1:numel(buttons)
                buttons(i).BackgroundColor = [0.2 0.2 0.2];
                buttons(i).FontColor = fg;
            end
        
            % Axes
            ax = app.ResultAxes;
            ax.Color = bg;
            ax.XColor = fg;
            ax.YColor = fg;
            title(ax, ax.Title.String, 'Color', fg);
            xlabel(ax, ax.XLabel.String, 'Color', fg);
            ylabel(ax, ax.YLabel.String, 'Color', fg);
        end

        
        function applyLightMode(app)
            bg = [0.94 0.94 0.94];
            fg = [0 0 0];
        
            app.UIFigure.Color = bg;
        
            % Panels
            panels = [ ...
                app.ParameterPanel, app.BildnavigationPanel, ...
                app.HauptauswahlPanel, app.KIBildInfosPanel, app.ErweiterteauswahlPanel ...
            ];
            for i = 1:numel(panels)
                panels(i).BackgroundColor = bg;
            end
        
            % Sliders
            % % sliders = [app.ThresholdSlider, app.thresholdslider2, app.TimelapseSlider];
            % % for i = 1:numel(sliders)
            % %     sliders(i).BackgroundColor = bg;
            % %     sliders(i).FontColor = fg;
            % % end
        
            % Labels (alle)
            labels = findall(app.UIFigure, 'Type', 'uilabel');
            for i = 1:numel(labels)
                labels(i).FontColor = fg;
                labels(i).BackgroundColor = 'none';
            end
        
            % TextArea
            app.DescriptionArea.BackgroundColor = [1 1 1];
            app.DescriptionArea.FontColor = fg;
        
            % % % Checkboxes
            % % for i = 1:6
            % %     cb = app.(['ClassCheckBox' num2str(i)]);
            % %     cb.FontColor = fg;
            % %     cb.BackgroundColor = bg;
            % % end
        
            % Dropdowns
            dropdowns = [ ...
                app.TimelapseModeDropDown, app.ChangeTypeDropDown, ...
                app.ChangeSizeDropDown, app.VisualizationDropDown ...
            ];
            for i = 1:numel(dropdowns)
                dropdowns(i).BackgroundColor = bg;
                dropdowns(i).FontColor = fg;
            end
        
            % Buttons
            buttons = [ ...
                app.PlayButton, app.PrevButton, app.NextButton, ...
                app.AnalyzeButton, app.LoadFolderButton, ...
                app.ThemeToggleButton, app.VisualisierungstartenButton, ...
                app.RestartButton, app.HelpButton ...
            ];
            for i = 1:numel(buttons)
                buttons(i).BackgroundColor = bg;
                buttons(i).FontColor = fg;
            end
        
            % Axes
            ax = app.ResultAxes;
            ax.Color = [1 1 1];
            ax.XColor = fg;
            ax.YColor = fg;
            title(ax, ax.Title.String, 'Color', fg);
            xlabel(ax, ax.XLabel.String, 'Color', fg);
            ylabel(ax, ax.YLabel.String, 'Color', fg);
        end


        function responseText = getGPTInfo(app, folderName)
            % GPT-Antwort über OpenAI HTTP API mit HTTP-Interface (robust)
        
            import matlab.net.*
            import matlab.net.http.*
        
            % === HIER DEIN API-KEY EINFÜGEN ===
            api_key = 'A';  % <-- OpenAI API Key eintragen
            prompt = sprintf("Gib mir eine kurze Beschreibung zur Region '%s'", folderName);

            % → GPT API Endpoint
            url = "https://api.openai.com/v1/chat/completions";
        
            % → Header korrekt erzeugen (nur char!)
            auth_value = ['Bearer ' api_key];  % <- garantiert char vector
        
            headers = [ ...
                matlab.net.http.HeaderField('Content-Type', 'application/json'), ...
                matlab.net.http.HeaderField('Authorization', auth_value) ...
            ];
        
            % → GPT JSON Request Body
            message = struct( ...
                'role', 'user', ...
                'content', prompt ...
            );
            
            body = struct( ...
                'model', 'gpt-3.5-turbo', ...
                'messages', { { message } } ...   % <<< Doppelte geschweifte Klammern!
            );
        
            % → HTTP Request senden
            request = matlab.net.http.RequestMessage('POST', headers, MessageBody(body));
        
            try
                response = send(request, URI(url));
            
                % Prüfen, ob GPT eine gültige Antwort gibt
                if isfield(response.Body.Data, "choices")
                    responseText = response.Body.Data.choices(1).message.content;
                elseif isfield(response.Body.Data, "error")
                    responseText = "❌ GPT-Fehler: " + response.Body.Data.error.message;
                else
                    responseText = "⚠️ Unerwartete Antwortstruktur von GPT.";
                    disp(response.Body.Data);
                end
            catch ME
                responseText = "❌ Ausnahme bei GPT-Anfrage: " + ME.message;
                disp(getReport(ME, 'extended'));
            end

        end


        function LoadFolder(app, ~)
            % Nutzer wählt einen Ordner
            folder = uigetdir();
            
            % Abbrechen gedrückt?
            if folder == 0
                return;
            end
        
            % Pfad speichern
            app.FolderPath = folder;
            app.ImageFiles = string.empty;
        
            % Unterstützte Bildformate
            validExtensions = {'.jpg', '.jpeg', '.png', '.tif', '.tiff'};
        
            % Inhalt des Ordners auflisten (nicht rekursiv)
            files = dir(fullfile(folder, '*'));
            for i = 1:length(files)
                [~,~,ext] = fileparts(files(i).name);
                if ismember(lower(ext), validExtensions)
                    app.ImageFiles(end+1) = string(files(i).name);
                end
            end
        
            % GPT-Beschreibung (optional)
            [~, folderName] = fileparts(folder);
            try
                infoText = getGPTInfo(app, folderName);
            catch
                infoText = "📄 Keine Beschreibung verfügbar.";
            end
            app.DescriptionArea.Value = infoText;
        
            % Folder Tree neu aufbauen
            buildFolderTree(app, app.FolderPath);
        
            
        end

        function runAnalysis(app, ~)
            if isempty(app.FolderPath) || isempty(app.FileListBox1.Value) || isempty(app.FileListBox2.Value)
                uialert(app.UIFigure, 'Bitte zwei Bilder auswählen.', 'Fehler');
                return;
            end

            % Bilder laden

            % Bildergröße angleichen
            img2 = imresize(img2, size(img1(:,:,1)));

            % Anzeigen


            % Visualisierungsmethode
            method = app.VisualizationDropDown.Value;
            switch method
                case 'Differenzbild'
                    diff = imabsdiff(rgb2gray(img1), rgb2gray(img2));
                    imshow(diff, [], 'Parent', app.ResultAxes);
                case 'Überlagerung'
                    overlay = imfuse(img1, img2, 'blend');
                    imshow(overlay, 'Parent', app.ResultAxes);
                case 'Flicker'
                    for i = 1:3
                        imshow(img1, 'Parent', app.ResultAxes);
                        pause(0.5);
                        imshow(img2, 'Parent', app.ResultAxes);
                        pause(0.5);
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [100, 100, 1158, 532];
            app.FolderPath = "";
            app.VisualizationDropDown.Value = 'Timelapse';
            %app.TimelapseModeDropDown.Items = {'Originalbilder', 'Differenzansicht'};
            app.TimelapseModeDropDown.Value = 'Originalbilder';
            app.TimelapseModeDropDown.Visible=true;
            app.PlayButton.Visible = true;
            app.NextButton.Visible=true;
            app.PrevButton.Visible=true;
            app.AbspielmodusTimelapseLabel.Visible=true;
            for i = 1:6
                app.(['ClassCheckBox' num2str(i)]).Value = true;
            end
            % Setze den ToggleButton "Turbo" auf AUS
            
        
            % Setze den angezeigten Text
            app.SchnellerModusButton.Text = 'Turbo AN';
            app.SchnellerModusButton.BackgroundColor = [0.2 0.8 0.2];
            app.SchnellerModusButton.FontColor = [1 1 1];
            app.SchnellerModusButton.Value = true;
            % (Optional: Farbe zurücksetzen)


            % Elemente mit Hover-Unterstützung
            app.HoverControls = {
                app.LoadFolderButton, ...
                app.AnalyzeButton, ...
                app.ChangeTypeDropDown, ...
                app.VisualizationDropDown, ...
                app.TimelapseModeDropDown, ...
                app.PlayButton, ...
                app.NextButton, ...
                app.PrevButton, ...
                app.ThemeToggleButton, ...
                app.ThresholdSlider
            };
            
            % Tooltips zuweisen
            %app.HoverControls{1}.UserData.Tooltip = "📁 Lade Satellitenbilder aus einem Ordner";
            app.HoverControls{2}.UserData.Tooltip = "🧠 Starte die Analyse der gewählten Bilder";
            app.HoverControls{3}.UserData.Tooltip = "🔍 Wähle die Art der Veränderung (z. B. Heatmap)";
            app.HoverControls{4}.UserData.Tooltip = "📊 Wähle die Art der Visualisierung";
            app.HoverControls{5}.UserData.Tooltip = "▶️ Time-Lapse Modus: manuell oder automatisch";
            app.HoverControls{6}.UserData.Tooltip = "▶ Starte automatische Wiedergabe";
            app.HoverControls{7}.UserData.Tooltip = "⏮ Vorheriges Bild anzeigen";
            app.HoverControls{8}.UserData.Tooltip = "⏭ Nächstes Bild anzeigen";
            app.HoverControls{9}.UserData.Tooltip = "🌗 Zwischen Dark und Light Mode wechseln";
            app.HoverControls{10}.UserData.Tooltip = "⚙ Schwellenwert für Änderung anpassen";
            
            % Tooltip-Label vorbereiten
            app.TooltipLabel = uilabel(app.UIFigure);
            app.TooltipLabel.Visible = false;
            app.TooltipLabel.BackgroundColor = [1 1 0.85];
            app.TooltipLabel.FontSize = 11;
            %app.TooltipLabel.BorderType = 'line';
            app.TooltipLabel.Position = [10, 10, 300, 22];
            
            % Event-Handler für Mausbewegung setzen
            app.UIFigure.WindowButtonMotionFcn = @(~,~) callMouseMoveHandlers(app);


            % Sichtbarkeit konfigurieren
            app.TimelapseModeDropDown.Visible = true;
            app.AbspielmodusTimelapseLabel.Visible=true;
            app.ThresholdLabel.Visible=false;
            app.PrevButton.Visible = true;
            app.NextButton.Visible = true;
            %app.PlayButton.Visible = false;
            %app.ThresholdSlider.Visible = false;
            % Start im Dark Mode
            
            
            % Button-Optik anpassen
            app.ThemeToggleButton.Text = "🌙 Dark Mode";           % Zeigt an: Dark Mode ist aktiv
            app.ThemeToggleButton.BackgroundColor = [0 0 0];       % Schwarzer Button
            app.ThemeToggleButton.FontColor = [1 1 1];             % Weiße Schrift
            % Mehrfachauswahl-Klassen unsichtbar
            for i = 1:6
                app.(['ClassCheckBox' num2str(i)]).Visible = false;
            end
            app.FolderTree.SelectionChangedFcn = @(src, event) folderNodeSelected(app, event);       
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            LoadFolder(app);
            %app.expandAllTreeNodes();
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
               % 📁 Ordner vorhanden?
            if isempty(app.FolderPath)
                uialert(app.UIFigure, 'Bitte zuerst einen Ordner laden.', 'Ordner fehlt');
                return;
            end
            app.VisualisierungstartenButton.Visible=false;
            try
                app.SpinnerImage.Visible = true;
                drawnow;
                % 🚀 SatelliteImageRegistration starten
                value = app.SchnellerModusButton.Value;
                sat = SatelliteImageRegistration(app.FolderPath, app,value);
                sat.run();  % Führt: loadImages → segment → register → transform aus
        
                % 💾 Ergebnisse in App speichern
                app.SatObj = sat;
                
                app.RegisteredImages = sat.registeredColorImages;
                app.ValidImageIndices = sat.validIndices;
                
                app.ReferenceIndex = sat.refIdx;
                app.TformList = sat.tformList;
        
                app.Segmentations = sat.segmentedMaps;
                app.TransformedSegmentations = sat.segmentedOverlapMasks;
                app.SpinnerImage.Visible = false;
                % ✅ Optional: Benutzer informieren
                %uialert(app.UIFigure, 'Analyse erfolgreich abgeschlossen.', 'Fertig');
                app.StatusLabel.Text = "✅ Analyse abgeschlossen";
                drawnow;
                pause(1.5);
                app.StatusLabel.Visible = false;
                app.VisualisierungstartenButton.Visible=true;
                app.colorTreeByValidity();
                app.ImageFiles = app.ImageFiles(app.ValidImageIndices);
            catch ME
                % ⚠️ Fehlerbehandlung
                app.SpinnerImage.Visible = false;
                uialert(app.UIFigure, ['Fehler während der Analyse: ' ME.message], 'Analysefehler');
                rethrow(ME); % Optional: vollständige Fehlermeldung im Command Window
            end


            cmap = [
                0 0 0; 0.2 0.55 0.5; 0.6 0.4 0.2; 1 0 0; 1 1 1;
                0 1 1
            ];
            classNames = {'Unbekannt','Water/Forest','Land','Urban/Agriculture','Snow','River/Road'};
             
            numImages = numel(app.TransformedSegmentations);
            cols = ceil(sqrt(numImages));
            rows = ceil(numImages / cols);
             
            % Step 1: Create the global zero-mask
            % Assume all segmentations are the same size
            mask = false(size(app.TransformedSegmentations{1}));
            for i = 1:numImages
                segImg = app.TransformedSegmentations{i};
                if ~isempty(segImg)
                    mask = mask | (segImg == 0);
                end
            end
             
            % Step 2: Apply the zero-mask to all images
            for i = 1:numImages
                segImg = app.TransformedSegmentations{i};
                if ~isempty(segImg)
                    segImg(mask) = 0;  % Force zero in masked regions
                    app.TransformedSegmentations{i} = segImg;  % Update with masked version
                end
            end
             
            % % % Step 3: Display the images
            % % figure('Name','Transformed Segmentations', ...
            % %        'NumberTitle','off', ...
            % %        'Position',[150, 150, min(1800, cols*300), min(1200, rows*250)]);
            % % 
            % % for i = 1:numImages
            % %     subplot(rows, cols, i);
            % %     segImg = app.TransformedSegmentations{i};
            % % 
            % %     if isempty(segImg)
            % %         axis off;
            % %         text(0.5, 0.5, 'Transform Failed', ...
            % %             'HorizontalAlignment','center', ...
            % %             'VerticalAlignment','middle', ...
            % %             'FontSize',12,'Color','red');
            % %     else
            % %         imshow(segImg, cmap);
            % %         title(sprintf('Warped %d', i), 'FontSize', 9);
            % %     end
            % % 
            % %     axis off;
            % % end
            % % 
            % % sgtitle('Transformed Segmented Images', 'FontSize', 14, 'FontWeight', 'bold');
            % % 
            % % % Optional: add color legend
            % % colormap(cmap);
            % % colorbar('Ticks', 0:numel(classNames)-1, ...
            % %          'TickLabels', classNames, ...
            % %          'TickLength', 0, 'Direction', 'reverse');
       
                
        end

        % Value changed function: VisualizationDropDown
        function VisualizationDropDownValueChanged(app, event)
                %delete(findall(app.UIFigure, 'Tag', 'customLegend'));
                stopTimelapseIfRunning(app)
                cb = findall(app.ResultAxes.Parent, 'Type', 'ColorBar');
                delete(cb);
                app.KIBildInfosPanel.Visible=true;
                app.DescriptionArea.Visible=true;
                app.timlapselabelslider.Visible=false;
                app.ChangeSizeDropDown.Visible=false;
                app.ChangeSizeDropDownlabel.Visible=false;
                % folder=app.FolderPath;
                % [~, folderName] = fileparts(folder);
                % 
                % infoText = getGPTInfo(app, folderName);
                %app.DescriptionArea.Value = infoText;
                app.TimelapseModeDropDown.Visible=false;
                value = app.VisualizationDropDown.Value;
                app.TimelapseSlider.Visible=false;
                app.ThresholdSlider.Visible=false;
                app.PrevButton.Visible=false;
                app.NextButton.Visible=false;
                % Alles ausblenden
                app.TimelapseModeDropDown.Visible = false;
                app.AbspielmodusTimelapseLabel.Visible = false;
                app.SegmentartLabel.Visible = false;
                app.PrevButton.Visible = false;
                app.NextButton.Visible = false;
                app.PlayButton.Visible=false;
                app.ChangeTypeDropDown.Visible = false;              % ZUSÄTZLICH AUSBLENDEN
                app.ArtderVernderungLabel.Visible = false;           % Optional: Label mit ausblenden
                app.ErweiterteauswahlPanel.Visible=false;
                app.DescriptionArea.Visible=true;
                app.KIBildInfosPanel.Visible=true;
                % Alle Dropdowns ausblenden außer Visualisierung
                %% === Dropdowns ausblenden ===
                app.ChangeSizeDropDown.Visible = false;
                app.ChangeTypeDropDown.Visible = false;
                app.TimelapseModeDropDown.Visible = false;
                
                % Nur dieser bleibt sichtbar
                app.VisualizationDropDown.Visible = true;
                
                %% === Labels ausblenden ===
                app.ChangeSizeDropDownlabel.Visible = false;
                app.ArtderVernderungLabel.Visible = false;
                app.AbspielmodusTimelapseLabel.Visible = false;
                
                % Nur dieses Label bleibt sichtbar
                app.ArtderVisualisierungLabel.Visible = true;
                
                %% === Slider ausblenden ===
                app.ThresholdSlider.Visible = false;
                app.thresholdslider2.Visible = false;
                app.TimelapseSlider.Visible = false;
                for i = 1:6
                        app.(['ClassCheckBox' num2str(i)]).Visible = false;
                end
                

                % --- Sichtbarkeiten je nach Auswahl ---
                if strcmp(value, 'Timelapse')
                    app.TimelapseModeDropDown.Visible = true;
                    app.AbspielmodusTimelapseLabel.Visible = true;
                    app.PrevButton.Visible=true;
                    app.NextButton.Visible=true;
                    app.PlayButton.Visible=true;
                    app.ThresholdSlider.Visible=false;
                    app.ThresholdLabel.Visible=false;
                    app.ThresholdLabel.Text = 'Änderung';
                    app.ThresholdLabel.Visible=false;

                elseif strcmp(value, 'Timelapse weich')

                    % 1. Jahreszahlen extrahieren (zuerst die Jahreszahl finden)
                    years = cellfun(@(f) extractBefore(f, '.'), app.ImageFiles, 'UniformOutput', false);
                    years = cellfun(@(name) name(end-1:end), years, 'UniformOutput', false);  % Nur letzte 2 Ziffern
                    
                    % 2. Slider konfigurieren
                    n = numel(years);
                    app.TimelapseSlider.Limits = [1 n];
                    app.TimelapseSlider.MajorTicks = 1:n;
                    app.TimelapseSlider.MajorTickLabels = years;

                    app.TimelapseSlider.Visible=true;

            
                elseif strcmp(value, 'Segmentierung')
                    app.SegmentartLabel.Visible = true;
                    app.AbspielmodusTimelapseLabel.Visible = false;
                    app.ErweiterteauswahlPanel.Visible=true;
                    app.DescriptionArea.Visible=false;
                    app.KIBildInfosPanel.Visible=false;
                    
            
                    for i = 1:6
                        app.(['ClassCheckBox' num2str(i)]).Visible = true;
                    end
                elseif strcmp(value, 'Statistik')
                    app.SegmentartLabel.Visible = false;
                    app.AbspielmodusTimelapseLabel.Visible = false;
                    app.ErweiterteauswahlPanel.Visible=false;


                elseif strcmp(value, 'Differenz-Highlights')        % NEU
                    app.ChangeTypeDropDown.Visible = true;
                    app.ArtderVernderungLabel.Visible = true;
                end
        end

        % Value changed function: ChangeTypeDropDown
        function ChangeTypeDropDownValueChanged(app, event)
            cb = findall(app.ResultAxes.Parent, 'Type', 'ColorBar');
            delete(cb);
            stopTimelapseIfRunning(app)
            app.KIBildInfosPanel.Visible=true;
            app.DescriptionArea.Visible=true;
            app.timlapselabelslider.Visible=false;
            app.ChangeSizeDropDown.Visible=false;
            app.ChangeSizeDropDownlabel.Visible=false;
            value = app.ChangeTypeDropDown.Value;
            app.PrevButton.Visible=false;
            app.NextButton.Visible=false;
            app.thresholdslider2.Visible=false;
            app.TimelapseModeDropDown.Visible=false;
            %delete(findall(app.UIFigure, 'Tag', 'customLegend'));
            app.TimelapseModeDropDown.Visible=false;
            % Standard: alles ausblenden
            app.ThresholdSlider.Visible = false;
            app.ThresholdLabel.Visible = false;
            for i = 1:6
            app.(['ClassCheckBox' num2str(i)]).Visible = false;
            end
            
            switch value
                case 'Langsam/Schnell'
                    % app.ThresholdSlider.Visible = true;
                    % app.ThresholdLabel.Visible = true;
                    % app.ThresholdLabel.Text = 'Geschwindigkeit';
                    % app.ThresholdSlider.Limits = [0 1];  % Beispiel: 0 = langsam, 100 = schnell
                    
                case 'Groß/Klein'
                    app.PrevButton.Visible = true;
                    app.NextButton.Visible = true;
                    app.ThresholdSlider.Visible = true;
                    app.ThresholdLabel.Visible = true;
                    app.ThresholdLabel.Text = 'Änderung';
                    app.ThresholdSlider.Limits = [0 1];  % Beispiel: 0 = langsam, 100 = schnell
                    app.ThresholdSlider.Value = 0.2;
                    app.thresholdslider2.Visible = true;
                    app.timlapselabelslider.Text='Flächen-Schwelle';
                    app.timlapselabelslider.Visible=true;
                   
                    app.thresholdslider2.Limits = [0 500];
                    app.thresholdslider2.Value = 200;
                    % NEU:
                    app.ChangeSizeDropDown.Visible = true;
                    app.ChangeSizeDropDownlabel.Visible=true;
                    %app.ChangeSizeLabel.Visible = true;
                    
                case 'Stadt/Land/Fluss'
                    app.ThresholdSlider.Visible = false;
                    app.ThresholdLabel.Visible = false;
                    app.ThresholdLabel.Text = 'Größe der Veränderung';
                    app.ThresholdSlider.Limits = [0 1];  % Beispiel in Pixeln oder m²
                    app.SegmentartLabel.Visible=true;

                    for i = 1:6
                        app.(['ClassCheckBox' num2str(i)]).Visible = true;
                    end
        

                case 'Chronische Veränderung'
                    app.ThresholdSlider.Visible = true;
                    app.ThresholdLabel.Visible = true;
                    app.ThresholdLabel.Text = 'Veränderung';
                    app.ThresholdSlider.Limits = [0 1];  % Beispiel in Pixeln oder m²
                    app.ThresholdSlider.Value = 0.15;
                    app.SegmentartLabel.Visible=true;

        
                case 'Heatmap'
                    app.ThresholdSlider.Visible=true;
                    app.ThresholdLabel.Visible=true;
            end


        end

        % Value changed function: TimelapseModeDropDown
        function TimelapseModeDropDownValueChanged(app, event)
            % value = app.TimelapseModeDropDown.Value;
            % 
            % % Erst alles ausblenden
             app.PrevButton.Visible = true;
             app.NextButton.Visible = true;
             app.PlayButton.Visible = true;
            % 
            
            % if strcmp(value, 'manuell')
            %     app.PrevButton.Visible = true;
            %     app.NextButton.Visible = true;
            % elseif strcmp(value, 'automatisch')
            %     app.PlayButton.Visible = true;
            %     app.PrevButton.Visible = false;
            %     app.NextButton.Visible = false;
            % end
        end

        % Button pushed function: ThemeToggleButton
        function ThemeToggleButtonPushed(app, event)
            app.isDarkMode = ~app.isDarkMode;

            if app.isDarkMode
                applyDarkMode(app);
                app.ThemeToggleButton.Text = "🌞 Light Mode";
                app.ThemeToggleButton.BackgroundColor = [1 1 1];       
                app.ThemeToggleButton.FontColor = [0 0 0];
            else
                applyLightMode(app);
                app.ThemeToggleButton.Text = "🌙 Dark Mode";
                app.ThemeToggleButton.BackgroundColor = [0 0 0];       
                app.ThemeToggleButton.FontColor = [1 1 1];
            end
        end

        % Button pushed function: VisualisierungstartenButton
        function VisualisierungstartenButtonPushed(app, event)
            selectedVis = app.VisualizationDropDown.Value;
            schwelle = app.ThresholdSlider.Value;
            bilder = app.RegisteredImages;
            app.CurrentTimelapseIndex = 2;;
            showSingleTimelapseFrame(app);


            switch selectedVis
                case 'Timelapse'
                    % 👉 Hier deine Timelapse-Funktion einbauen
                    mode = app.TimelapseModeDropDown.Value;
                    bilder = app.RegisteredImages;
                    schwelle = app.ThresholdSlider.Value;
                    app.NextButton.Visible=true;
                    %app.
                    
                    if isempty(bilder)
                        uialert(app.UIFigure, ...
                            'Bitte zuerst eine Analyse durchführen.', 'Keine Daten');
                        return;
                    end
                    app.CurrentTimelapseIndex = 2;
                    stopTimelapseIfRunning(app);   % ⏹️ Falls noch läuft
                
                    showSingleTimelapseFrame(app);
                    
                    switch mode
                        case 'Originalbilder'
                            % Nur Bilder zeigen
                            %timelapse_bilderfolge(bilder, false, schwelle, app.ResultAxes);
                            app.CurrentTimelapseIndex = 1;
                            showSingleTimelapseFrame(app);
                        case 'Differenzansicht'
                            %%timelapse_bilderfolge(bilder, true, schwelle, app.ResultAxes);
                            %app.TimelapseFrames = timelapse_bilderfolge(bilder, true, schwelle);
                            showSingleTimelapseFrame(app);

                        case 'Segmentierung'
                            app.CurrentSegmentationIndex = 1;
                            showSegmentationImage(app);

                        otherwise
                            uialert(app.UIFigure, 'Unbekannter Abspielmodus.', 'Fehler');
                    end

                    disp("Timelapse-Funktion wird ausgeführt.");

                % % case 'Timelapse weich'
                % %     bilder = app.RegisteredImages;
                % %     if isempty(bilder) || numel(bilder) < 2
                % %         uialert(app.UIFigure, 'Mindestens zwei Bilder nötig.', 'Fehler');
                % %         return;
                % %     end
                % % 
                % %     app.TimelapseSlider.Visible = true;
                % %     app.TimelapseSlider.Limits = [1, numel(bilder)];
                % %     app.TimelapseSlider.Value = 1;
                % % 
                % %     % Erstes Bild anzeigen
                % %     imshow(bilder{1}, 'Parent', app.ResultAxes);
                % %     title(app.ResultAxes, sprintf('Frame 1 / %d', numel(bilder)));
                % % 
                % %     % Slider Callback aktivieren
                % %     app.TimelapseSlider.ValueChangedFcn = @(src,event) timelapseSlider_Update(app);
                % % 
                case 'Timelapse weich'
                    bilder = app.RegisteredImages;
                    if isempty(bilder) || numel(bilder) < 2
                        uialert(app.UIFigure, 'Mindestens zwei Bilder nötig.', 'Fehler');
                        return;
                    end
                
                    app.TimelapseSlider.Visible = true;
                    % app.TimelapseSlider.Limits = [1, numel(bilder)];
                    % app.TimelapseSlider.MajorTicks = 1:numel(bilder);  % Optional für Ganzzahlen
                    % app.TimelapseSlider.Value = 1;
                
                    % Callback bei Bewegung zuweisen
                    app.TimelapseSlider.ValueChangingFcn = @(src, event) TimelapseSliderValueChanging(app, event);
                
                    % Initial anzeigen
                    timelapseSlider_Update(app);

                case 'Segmentierung'
                    masks = app.TransformedSegmentations;
                    files = app.ImageFiles;
                    app.KIBildInfosPanel.Visible=false;
                    
                    
                    if isempty(masks)
                        uialert(app.UIFigure, 'Bitte zuerst Analyse durchführen.', 'Fehler');
                        return;
                    end
                    
                    % Ausgewählte Klassen aus Checkboxes
                    selectedClasses = [];
                    for i = 1:6
                        cb = app.(['ClassCheckBox' num2str(i)]);
                        if cb.Value
                            selectedClasses(end+1) = i - 1;  % Klasse 0–7
                        end
                    end
                
                    if isempty(selectedClasses)
                        uialert(app.UIFigure, 'Bitte mindestens eine Klasse auswählen.', 'Fehlende Auswahl');
                        return;
                    end
                
                    % Funktionsaufruf mit ResultAxes
                    Plot_Class_Heatmap(masks, files, app.ResultAxes, selectedClasses);
                    disp("Segmentierungs-Visualisierung wird ausgeführt.");
        
                case 'Statistik'
                    masks = app.TransformedSegmentations(app.ValidImageIndices);
                    files = app.ImageFiles;
                
                    if isempty(masks) || isempty(files)
                        uialert(app.UIFigure, 'Bitte zuerst eine Analyse durchführen.', 'Fehlende Daten');
                        return;
                    end
                
                    Plot_Class_Percentages_Over_Time(masks, files);
                    cla(app.ResultAxes);  % Achse leeren
                    text(app.ResultAxes, 0.5, 0.5, 'Bild im separaten Fenster geöffnet', ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle', ...
                        'FontSize', 14, ...
                        'FontWeight', 'bold');
                    axis(app.ResultAxes, 'off');
                    
                    title(app.ResultAxes, '', 'FontWeight', 'normal');
                    disp("Statistik-Visualisierung wird ausgeführt.");
        
                case 'Differenz-Highlights'
                    changeType = app.ChangeTypeDropDown.Value;
        
                    switch changeType
                        case 'Heatmap'
                            updateHeatmapVisualization(app);  % 🔥 Heatmap wie vorhin
        
                        case 'Langsam/Schnell'
                            %  Funktion für Geschwindigkeit (z. B. Farbanimation)
                            visualize_schnell_langsam(app.RegisteredImages, app.ResultAxes);
                            addSpeedLegendToApp(app)
                            disp("Langsam/Schnell wird analysiert.");
                        % case 'Veränderungshäufigkeit'
                        %     heatmap_veraenderungshauefigkeit(app.RegisteredImages, app.ThresholdSlider.Value, app.ResultAxes);

                        case 'Groß/Klein'
                            if isempty(app.RegisteredImages)
                                uialert(app.UIFigure, 'Bitte zuerst Analyse durchführen.', 'Keine Daten');
                                return;
                            end
                            app.ThresholdSlider.Visible=true;
                            app.thresholdslider2.Visible=true;
                            modus = app.ChangeSizeDropDown.Value;        % 'klein' | 'gross' | 'alle'
                            schwelle = app.ThresholdSlider.Value;        % z. B. 0.18
                            flaechenGrenze = app.thresholdslider2.Value;  % z. B. 200
                        
                            % Ergebnisbilder erzeugen
                            verBilder = visualisiere_veraenderungen_nach_flaeche( ...
                                            app.RegisteredImages, modus, schwelle, flaechenGrenze);
                        
                            % In separatem Property speichern
                            app.ChangeSizeImages = verBilder;
                        
                            % Steuerung vorbereiten
                            app.CurrentTimelapseIndex = 1;
                            showChangeSizeImage(app);  % Eigene Anzeige-Funktion

                       case 'Stadt/Land/Fluss'
                            % 👉 Mögliche segmentbasierte Anzeige
                            disp("Segmentbasierte Veränderungsauswertung.");
                       case 'Chronische Veränderung'


                            if isempty(bilder) || numel(bilder) < 2
                                uialert(app.UIFigure, 'Bitte führe zuerst die Analyse durch.', 'Fehlende Daten');
                                return;
                            end
                        
                            % Aufruf der ausgelagerten Funktion
                            Veraenderungszeitpunkte(app.RegisteredImages, app.ImageFiles, app.ThresholdSlider.Value, app.ResultAxes);


                    end
                    app.StatusLabel.Text = "✅ Analyse abgeschlossen";

                otherwise
                    uialert(app.UIFigure, 'Unbekannte Visualisierung ausgewählt.', 'Fehler');
            end

        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            % value = app.ThresholdSlider.Value;
            % %updateHeatmapVisualization(app);
            % % Aktualisiert aktuelle Timelapse-Ansicht, wenn Slider verändert wird
            % if strcmp(app.VisualizationDropDown.Value, 'Timelapse')
            %     timelapse_bilderfolge(app, 'manuell', '');
            % end

            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % if isempty(app.RegisteredImages) || ~strcmp(app.VisualizationDropDown.Value, 'Timelapse')
            %     return;
            % end
            % 
            % modus = app.TimelapseModeDropDown.Value;
            % 
            % if app.IsPlaying
            %     stopTimelapseIfRunning(app);
            % else
            %     % 🟢 Wenn am Ende → Wiederanfang
            %     maxIdx = numel(app.RegisteredImages);
            %     if strcmp(modus, 'Originalbilder')
            %         if app.CurrentTimelapseIndex > maxIdx
            %             app.CurrentTimelapseIndex = 1;
            %         end
            %     else
            %         if app.CurrentTimelapseIndex >= maxIdx
            %             app.CurrentTimelapseIndex = 2;
            %         end
            %     end
            % 
            %     % Timer starten
            %     if isempty(app.TimelapseTimer) || ~isvalid(app.TimelapseTimer)
            %         app.TimelapseTimer = timer( ...
            %             'ExecutionMode','fixedRate', ...
            %             'Period', 1.2, ...
            %             'TimerFcn', @(~,~) autoAdvanceTimelapse(app));
            %     end
            %     app.IsPlaying = true;
            %     start(app.TimelapseTimer);
            % end
            % % if ~app.IsPlaying
            % %     app.IsPlaying = true;
            % %     app.PlayButton.Text = 'Pause';
            % % 
            % %     app.TimelapseTimer = timer( ...
            % %         'ExecutionMode', 'fixedRate', ...
            % %         'Period', 0.8, ...  % Sekunden pro Frame
            % %         'TimerFcn', @(~,~) nextFrame(app));
            % % 
            % %     start(app.TimelapseTimer);
            % % else
            % %     stop(app.TimelapseTimer);
            % %     delete(app.TimelapseTimer);
            % %     app.TimelapseTimer = [];
            % %     app.IsPlaying = false;
            % %     app.PlayButton.Text = 'Play';
            % % end
            if app.IsPlaying
                % Timer stoppen
                stop(app.TimelapseTimer);
                delete(app.TimelapseTimer);
                app.IsPlaying = false;
                app.PlayButton.Text = '▶️ Play';
                return;
            end
        
            % Timer konfigurieren
            app.TimelapseTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.6, ...
                'TimerFcn', @(~,~) runNextFrame(app));
        
            start(app.TimelapseTimer);
            app.IsPlaying = true;
            app.PlayButton.Text = '⏸️ Pause';
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            modus = app.TimelapseModeDropDown.Value;
        
            % Groß/Klein – Differenzbild
            if strcmp(app.ChangeTypeDropDown.Value, 'Groß/Klein') && ...
               strcmp(app.VisualizationDropDown.Value, 'Differenz-Highlights')
                app.CurrentTimelapseIndex = max(1, app.CurrentTimelapseIndex - 1);
                showChangeSizeImage(app);
                return;
            end
        
            % Segmentierung
            if strcmp(modus, 'Segmentierung')
                app.CurrentSegmentationIndex = max(1, app.CurrentSegmentationIndex - 1);
                showSegmentationImage(app);
                return;
            end
        
            % Original oder Differenz
            if strcmp(modus, 'Originalbilder')
                if app.CurrentTimelapseIndex > 1
                    app.CurrentTimelapseIndex = app.CurrentTimelapseIndex - 1;
                end
            else
                if app.CurrentTimelapseIndex > 2
                    app.CurrentTimelapseIndex = app.CurrentTimelapseIndex - 1;
                end
            end
        
            showSingleTimelapseFrame(app);

% Gemeinsame Anzeige (außer für Seg
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            modus = app.TimelapseModeDropDown.Value;
            maxIndex = numel(app.RegisteredImages);
            
            % Groß/Klein-Analyse + Differenz-Highlights
            if strcmp(app.ChangeTypeDropDown.Value, 'Groß/Klein') && ...
               strcmp(app.VisualizationDropDown.Value, 'Differenz-Highlights')
                app.CurrentTimelapseIndex = min(numel(app.ChangeSizeImages), app.CurrentTimelapseIndex + 1);
                showChangeSizeImage(app);
                return;
            end
            
            % Segmentierung-Modus
            if strcmp(modus, 'Segmentierung')
                app.CurrentSegmentationIndex = min(numel(app.TransformedSegmentations), app.CurrentSegmentationIndex + 1);
                showSegmentationImage(app);
                return;
            end
            
            % Originalbilder-Modus
            if strcmp(modus, 'Originalbilder')
                if app.CurrentTimelapseIndex < maxIndex
                    app.CurrentTimelapseIndex = app.CurrentTimelapseIndex + 1;
                else
                    app.CurrentTimelapseIndex = 1;  % zurück auf erstes Bild
                end
            else
                % Für alle anderen Modi: Differenz etc.
                if app.CurrentTimelapseIndex < maxIndex
                    app.CurrentTimelapseIndex = app.CurrentTimelapseIndex + 1;
                else
                    app.CurrentTimelapseIndex = 2;  % Differenz braucht mind. 2
                end
            end
            
            % Bild anzeigen (für alles außer Segmentierung & Groß/Klein)
            showSingleTimelapseFrame(app);
                                
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
             % Neues Hilfe-Fenster erzeugen
            helpFig = uifigure('Name', '📘 Hilfe zur App', 'Position', [100 100 1500 700]);
            
            % Textbereich (links) – leicht verschmälert
            helpText = uitextarea(helpFig);
            helpText.Position = [30 60 570 580];  % Etwas schmaler, aber hoch
            helpText.Editable = 'off';
            
            % Bild (rechts) – größer und weiter nach rechts verschoben
            helpImg = uiimage(helpFig);
            helpImg.Position = [620 60 880 580];  % Breiter, bleibt im Fenster
            helpImg.ImageSource = 'help_image.jpg';
            helpText.Value = {
                '===================================================='
                'OBERFLÄCHENELEMENTE – ÜBERSICHT:'
                '===================================================='
                ''
                '1  – Hilfe-Fenster (dieser Bereich)'
                '2  – KI Bild-Info Panel (zeigt allgemeine Infos zum Bild – z. B. Stadt, Jahr, Thema)'
                '3  – Visualisierungsmethode Dropdown (z. B. Segmentierung, Timelapse …)'
                '4  – Veränderungstyp Dropdown (z. B. Differenz-Highlights – nur bei Bedarf sichtbar)'
                '5  – Veränderungsdetail / Subtyp Dropdown (z. B. Groß/Klein, Schnell/Langsam) – wird nur eingeblendet, wenn relevant'
                '6  – Parameter (z. B. Slider für Schwellwert – nur sichtbar, wenn notwendig)'
                '7  – Ordnerstruktur-Ansicht (zeigt geladene Dateien und deren Status)'
                '8  – Ordner laden Button'
                '9  – Analyse starten Button'
                '10 – Visualisierung starten Button'
                '11 – Turbo-Modus Button (beschleunigt Analyse bei vielen Bildern)'
                '12 – Neustart Button (setzt App zurück)'
                '13 – Bildnavigation (Slider, Vor-/Zurück-Buttons)'
                '14 – Ergebnisanzeige (Achse für Visualisierung)'
                ''
                '===================================================='
                'BEDIENUNGSANLEITUNG – SCHRITT FÜR SCHRITT'
                '===================================================='
                ''
                '1. Ordner laden (Button 9)'
                '   – Wähle einen Ordner mit Bilddateien (keine Unterordner!).'
                ''
                '2. Turbo-Modus aktivieren/deaktivieren (Button 12)'
                '   – Standardmäßig aktiv (schneller).'
                '   – Bei deaktiviertem Turbo wird die Analyse detailreicher, aber langsamer.'
                ''
                '3. Analyse starten (Button 10)'
                '   – Führt Registrierung, Segmentierung und Datenvorverarbeitung durch.'
                ''
                '4. Visualisierungsmethode wählen (Dropdown 3)'
                '   – Entscheidet über die Darstellungsart (z. B. Timelapse, Segmentierung).'
                ''
                '5. Veränderungstyp und Details (Dropdowns 4 & 5)'
                '   – Diese Dropdowns erscheinen nur, wenn sie zur Methode passen.'
                ''
                '6. Parameter anpassen (z. B. Schwellwert – Bereich 6)'
                '   – Nur sichtbar, wenn für die Methode erforderlich.'
                ''
                '7. Visualisierung starten (Button 11)'
                '   – Muss immer erneut gedrückt werden, wenn du Dropdowns oder Parameter änderst.'
                ''
                '8. Bildnavigation (Bereich 14)'
                '   – Blättere manuell durch die Zeitreihe.'
                ''
                '9. Bildanzeige (Bereich 15)'
                '   – Visualisierte Ergebnisse werden hier dargestellt.'
                ''
                '10. Zusatzinfos (Bereich 2 – KI Bild-Info)'
                '   – Zeigt z. B. Ort, Aufnahmedatum, Zusatzinfos zum Bild.'
                ''
                '===================================================='
                'VISUALISIERUNGSMETHODEN'
                '===================================================='
                ''
                'A) Differenz-Highlights'
                '   – Heatmap: Häufigkeit von Veränderungen pro Pixel.'
                '   – Groß/Klein: Visualisiert große oder kleine Veränderungen.'
                '   – Schnell/Langsam: Zeigt abrupte (rot) oder schleichende (blau) Veränderungen.'
                '   – Chronische Veränderung: Farbkodierte Darstellung der Veränderungszeitpunkte.'
                ''
                'B) Timelapse'
                '   – Originalbilder: Zeigt die ursprüngliche Bildreihe.'
                '   – Differenzbilder: Hebt Unterschiede zwischen aufeinanderfolgenden Bildern hervor.'
                '   – Segmentierung: Zeigt farbcodierte Klassen pro Zeitstempel.'
                ''
                'C) Timelapse Weich'
                '   – Zeigt fließende Übergänge zwischen registrierten Bildern.'
                ''
                'D) Segmentierung'
                '   – Zeigt für jedes Bild die erkannte Klasse je Pixel (z. B. Wasser, Wald, Stadt).'
                ''
                'E) Statistik'
                '   – Zeigt die prozentuale Häufigkeit der Klassen über die Zeit in einem Diagramm.'
                ''
                'Hinweise:'
                '– Nach jeder Änderung muss „Visualisierung starten“ gedrückt werden.'
                '– Nicht benötigte Parameterfelder sind automatisch ausgeblendet.'
                '– Einige Methoden öffnen ein separates Fenster – dann erscheint in der App: „Bild im externen Fenster geöffnet“.'
            };


                uibutton(helpFig, ...
                    'Position', [700 20 120 30], ...  % (1500 - 100) / 2 = 700
                    'Text', 'Fenster schließen', ...
                    'ButtonPushedFcn', @(btn, event) close(helpFig));
         
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            answer = uiconfirm(app.UIFigure, ...
                'Möchtest du die App wirklich neu starten?', ...
                'Neu starten?', ...
                'Options', {'Ja','Nein'}, ...
                'DefaultOption', 'Nein', ...
                'Icon', 'question');
            
            if strcmp(answer, 'Ja')
                ChangeDetectionApp();  % Neue App starten
                delete(app);           % Aktuelle Instanz schließen
            end
        end

        % Value changing function: TimelapseSlider
        function TimelapseSliderValueChanging(app, event)
            app.TimelapseSlider.Value = event.Value;     % Slider-Wert setzen
            timelapseSlider_Update(app);    
            
        end

        % Value changed function: SchnellerModusButton
        function SchnellerModusButtonValueChanged(app, event)
            value = app.SchnellerModusButton.Value;
            if app.SchnellerModusButton.Value
                app.SchnellerModusButton.Text = 'Turbo AN';
                app.SchnellerModusButton.BackgroundColor = [0.2 0.8 0.2];
                app.SchnellerModusButton.FontColor = [1 1 1];
            else
                app.SchnellerModusButton.Text = 'Turbo AUS';
                app.SchnellerModusButton.BackgroundColor = [0.94 0.94 0.94];
                app.SchnellerModusButton.FontColor = [0 0 0];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResultAxes = uiaxes(app.UIFigure);
            title(app.ResultAxes, 'Visualisierung')
            app.ResultAxes.XTick = [];
            app.ResultAxes.YTick = [];
            app.ResultAxes.ZTick = [];
            app.ErweiterteauswahlPanel = uipanel(app.UIFigure);
            app.ErweiterteauswahlPanel.Title = 'Erweiterteauswahl';
            app.ErweiterteauswahlPanel.Visible = 'off';
            app.KIBildInfosPanel = uipanel(app.UIFigure);
            app.KIBildInfosPanel.Title = 'KI Bild Infos';
            app.HauptauswahlPanel = uipanel(app.UIFigure);
            app.HauptauswahlPanel.Title = 'Hauptauswahl';
            app.ArtderVisualisierungLabel = uilabel(app.HauptauswahlPanel);
            app.ArtderVisualisierungLabel.Text = 'Visualisierungsmethode';
            app.VisualizationDropDown = uidropdown(app.HauptauswahlPanel);
            app.VisualizationDropDown.Items = {'Differenz-Highlights', 'Timelapse', 'Timelapse weich', 'Segmentierung', 'Statistik'};
            app.VisualizationDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizationDropDownValueChanged, true);
            app.VisualizationDropDown.Value = 'Timelapse';
            app.ArtderVernderungLabel = uilabel(app.HauptauswahlPanel);
            app.ArtderVernderungLabel.Visible = 'off';
            app.ArtderVernderungLabel.Text = 'Veränderungstyp';
            app.ChangeTypeDropDown = uidropdown(app.HauptauswahlPanel);
            app.ChangeTypeDropDown.Items = {'Heatmap', 'Groß/Klein', 'Langsam/Schnell', 'Chronische Veränderung'};
            app.ChangeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeTypeDropDownValueChanged, true);
            app.ChangeTypeDropDown.Visible = 'off';
            app.ChangeTypeDropDown.Value = 'Heatmap';
            app.FolderTree = uitree(app.HauptauswahlPanel);
            app.Node = uitreenode(app.FolderTree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.OrdnerStrukturLabel = uilabel(app.HauptauswahlPanel);
            app.OrdnerStrukturLabel.Text = 'Ordner Struktur';
            app.ChangeSizeDropDown = uidropdown(app.HauptauswahlPanel);
            app.ChangeSizeDropDown.Items = {'Groß', 'Klein', 'Alle'};
            app.ChangeSizeDropDown.Visible = 'off';
            app.ChangeSizeDropDown.Value = 'Groß';
            app.ChangeSizeDropDownlabel = uilabel(app.HauptauswahlPanel);
            app.ChangeSizeDropDownlabel.Visible = 'off';
            app.ChangeSizeDropDownlabel.Text = 'Veränderungsart';
            app.AbspielmodusTimelapseLabel = uilabel(app.HauptauswahlPanel);
            app.AbspielmodusTimelapseLabel.Visible = 'off';
            app.AbspielmodusTimelapseLabel.Text = 'Abspielmodus (Timelapse)';
            app.TimelapseModeDropDown = uidropdown(app.HauptauswahlPanel);
            app.TimelapseModeDropDown.Items = {'Originalbilder', 'Segmentierung', 'Differenzansicht'};
            app.TimelapseModeDropDown.ValueChangedFcn = createCallbackFcn(app, @TimelapseModeDropDownValueChanged, true);
            app.TimelapseModeDropDown.Visible = 'off';
            app.TimelapseModeDropDown.Value = 'Originalbilder';
            app.RestartButton = uibutton(app.HauptauswahlPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = '🔄 Neu starten';
            app.SchnellerModusButton = uibutton(app.HauptauswahlPanel, 'state');
            app.SchnellerModusButton.ValueChangedFcn = createCallbackFcn(app, @SchnellerModusButtonValueChanged, true);
            app.SchnellerModusButton.Text = 'Schneller Modus';
            app.LoadFolderButton = uibutton(app.UIFigure, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Tooltip = {''};
            app.LoadFolderButton.Text = 'Ordner laden';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyse starten';
            app.ClassCheckBox1 = uicheckbox(app.UIFigure);
            app.ClassCheckBox1.Visible = 'off';
            app.ClassCheckBox1.Text = 'Unbekannt';
            app.ClassCheckBox2 = uicheckbox(app.UIFigure);
            app.ClassCheckBox2.Visible = 'off';
            app.ClassCheckBox2.Text = 'Wasser/Wald';
            app.ClassCheckBox3 = uicheckbox(app.UIFigure);
            app.ClassCheckBox3.Visible = 'off';
            app.ClassCheckBox3.Text = 'Land';
            app.ClassCheckBox4 = uicheckbox(app.UIFigure);
            app.ClassCheckBox4.Visible = 'off';
            app.ClassCheckBox4.Text = 'Urban/Landwirtschaft';
            app.ClassCheckBox5 = uicheckbox(app.UIFigure);
            app.ClassCheckBox5.Visible = 'off';
            app.ClassCheckBox5.Text = 'Schnee';
            app.ClassCheckBox6 = uicheckbox(app.UIFigure);
            app.ClassCheckBox6.Visible = 'off';
            app.ClassCheckBox6.Text = 'Fluss/Straße';
            app.SegmentartLabel = uilabel(app.UIFigure);
            app.SegmentartLabel.Visible = 'off';
            app.SegmentartLabel.Text = 'Segmentart';
            app.ThemeToggleButton = uibutton(app.UIFigure, 'push');
            app.ThemeToggleButton.ButtonPushedFcn = createCallbackFcn(app, @ThemeToggleButtonPushed, true);
            app.ThemeToggleButton.Visible = 'off';
            app.ThemeToggleButton.Text = '🌙 Dark Mode';
            app.VisualisierungstartenButton = uibutton(app.UIFigure, 'push');
            app.VisualisierungstartenButton.ButtonPushedFcn = createCallbackFcn(app, @VisualisierungstartenButtonPushed, true);
            app.VisualisierungstartenButton.Text = 'Visualisierung starten';
            app.SpinnerImage = uiimage(app.UIFigure);
            app.SpinnerImage.Visible = 'off';
            app.SpinnerImage.ImageSource = 'loading-loading-gif.gif';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Visible = 'off';
            app.StatusLabel.Text = 'Label2';
            app.BildnavigationPanel = uipanel(app.UIFigure);
            app.BildnavigationPanel.Title = 'Bildnavigation';
            app.TimelapseSlider = uislider(app.BildnavigationPanel);
            app.TimelapseSlider.ValueChangingFcn = createCallbackFcn(app, @TimelapseSliderValueChanging, true);
            app.TimelapseSlider.Visible = 'off';
            app.NextButton = uibutton(app.BildnavigationPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Tooltip = {''};
            app.NextButton.Text = ' Vor→';
            app.PrevButton = uibutton(app.BildnavigationPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Visible = 'off';
            app.PrevButton.Text = '← Zurück';
            app.PlayButton = uibutton(app.BildnavigationPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = '▶️ Auto play';
            app.ParameterPanel = uipanel(app.UIFigure);
            app.ParameterPanel.Title = 'Parameter';
            app.ThresholdSlider = uislider(app.ParameterPanel);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.Visible = 'off';
            app.ThresholdSlider.Value = 0.2;
            app.ThresholdLabel = uilabel(app.ParameterPanel);
            app.ThresholdLabel.Visible = 'off';
            app.thresholdslider2 = uislider(app.ParameterPanel);
            app.thresholdslider2.Visible = 'off';
            app.timlapselabelslider = uilabel(app.ParameterPanel);
            app.timlapselabelslider.Visible = 'off';
            app.timlapselabelslider.Text = 'Label3';
            app.DescriptionArea = uitextarea(app.UIFigure);
            app.DescriptionArea.Editable = 'off';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '❓ Hilfe';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChangeDetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end